classdef app_advancedFeature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedFeatureUIFigure    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LinePanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        MiddleLineHyperlink        matlab.ui.control.Hyperlink
        MiddleLineCheckBox         matlab.ui.control.CheckBox
        InternalLineHyperlink      matlab.ui.control.Hyperlink
        InternalLineCheckBox       matlab.ui.control.CheckBox
        DenseHyperlink             matlab.ui.control.Hyperlink
        DenseSegmentationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The calling app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AdvancedFeatureUIFigure.Visible = "off";
            movegui(app.AdvancedFeatureUIFigure, "center");
            app.AdvancedFeatureUIFigure.Visible = "on";

            % Store inputs
            app.callingApp = caller;
        end

        % Value changed function: DenseSegmentationCheckBox
        function DenseSegmentationCheckBoxValueChanged(app, event)
            value = app.DenseSegmentationCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.InternalLineCheckBox.Enable = "off";
                app.MiddleLineCheckBox.Enable ="off";
            else
                app.InternalLineCheckBox.Enable = "on";
                app.MiddleLineCheckBox.Enable = "off";
            end

            app.callingApp.setDense(value);
        end

        % Value changed function: InternalLineCheckBox
        function InternalLineCheckBoxValueChanged(app, event)
            value = app.InternalLineCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.DenseSegmentationCheckBox.Enable = "off";
                app.MiddleLineCheckBox.Enable = "off";
            else
                app.DenseSegmentationCheckBox.Enable = "on";
                app.MiddleLineCheckBox.Enable = "on";
            end

            app.callingApp.setInternalLine(value);
        end

        % Callback function: DenseHyperlink
        function DenseHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeatureUIFigure, "Set to true if a dense object is present and you wants more checks to be able to recognize open lines and close dense lines.", "Dense Segmentation", "Options", "OK", "Icon", "info");
        end

        % Callback function: InternalLineHyperlink
        function InternalLineHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeatureUIFigure, "If true, the internal line is required (when segmentation of more than one pixel).", "Internal Line", "Options", "OK", "Icon", "info");
        end

        % Close request function: AdvancedFeatureUIFigure
        function AdvancedFeatureUIFigureCloseRequest(app, event)
            app.callingApp.enableAdvancedFeatures();
            delete(app);
        end

        % Callback function: MiddleLineHyperlink
        function MiddleLineHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeatureUIFigure, "If true, the 1-pixel middle line is required (when segmentation of more than one pixel).", "Middle Line", "Options", "OK", "Icon", "info");
        end

        % Value changed function: MiddleLineCheckBox
        function MiddleLineCheckBoxValueChanged(app, event)
            value = app.MiddleLineCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.DenseSegmentationCheckBox.Enable = "off";
                app.InternalLineCheckBox.Enable = "off";
            else
                app.DenseSegmentationCheckBox.Enable = "on";
                app.InternalLineCheckBox.Enable = "on";
            end
                        
            app.callingApp.setMiddleLine(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedFeatureUIFigure = uifigure('Visible', 'off');
            app.AdvancedFeatureUIFigure.Name = 'Advanced Feature';
            app.AdvancedFeatureUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdvancedFeatureUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.AdvancedFeatureUIFigure);
            app.DenseSegmentationCheckBox = uicheckbox(app.GridLayout);
            app.DenseSegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenseSegmentationCheckBoxValueChanged, true);
            app.DenseSegmentationCheckBox.Text = 'Dense Segmentation';
            app.DenseHyperlink = uihyperlink(app.GridLayout);
            app.DenseHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @DenseHyperlinkClicked, true);
            app.DenseHyperlink.Text = 'Help ...';
            app.LinePanel = uipanel(app.GridLayout);
            app.LinePanel.Title = 'Line';
            app.GridLayout2 = uigridlayout(app.LinePanel);
            app.InternalLineCheckBox = uicheckbox(app.GridLayout2);
            app.InternalLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @InternalLineCheckBoxValueChanged, true);
            app.InternalLineCheckBox.Text = 'Internal';
            app.InternalLineHyperlink = uihyperlink(app.GridLayout2);
            app.InternalLineHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @InternalLineHyperlinkClicked, true);
            app.InternalLineHyperlink.Text = 'Help ...';
            app.MiddleLineCheckBox = uicheckbox(app.GridLayout2);
            app.MiddleLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @MiddleLineCheckBoxValueChanged, true);
            app.MiddleLineCheckBox.Text = 'Middle';
            app.MiddleLineHyperlink = uihyperlink(app.GridLayout2);
            app.MiddleLineHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @MiddleLineHyperlinkClicked, true);
            app.MiddleLineHyperlink.Text = 'Help...';
            % Show the figure after all components are created
            app.AdvancedFeatureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_advancedFeature(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedFeatureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedFeatureUIFigure)
        end
    end
end