classdef imageSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ImageGridLayout  matlab.ui.container.GridLayout
        Image            matlab.ui.control.Image
        CloseButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Main app
        imageFullName % File FullName (path/name) of the image to display
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imageFullName)
            % Center the dialog box
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, "center");
            app.UIFigure.Visible = "on";
            
            % TODO:
            % - Handle 1 channel image 
            % - look at im2bw

            % Store input params
            app.callingApp = caller;
            app.imageFullName = imageFullName;

            img = imread(app.imageFullName);
            
            % If the image has more than one channel but is not an rgb
            % image, use only the first one
            if size(img,3) > 1 && size(img,3) ~= 3
                img = img(:,:,1);
                waitfor( warndlg("Used only the first channel of the image") ); % Wait until ok is pressed
            end

            % Convert to 8-bit image
            img = im2uint8(img);

            % Convert to grayscale 
            img = im2gray(img);
            
            % Get four border points to get the bg color
            [rows, cols] = size(img);
            p1= img(1, ceil( cols / 2 ) );
            p2 = img(rows, ceil( cols / 2 ) );
            p3 = img( ceil( rows / 2 ), 1);
            p4 = img( ceil(rows / 2) , cols);

            if isequal(p1, 0) && isequal(p2, 0) && isequal(p3, 0) && isequal(p4, 0)
                
                for i = 1:rows
                    for j = 1:cols
                        if img(i,j) > 0
                            img(i,j) = 1;
                        end
                    end
                end


            else
                
                for i = 1:rows
                    for j = 1:cols
                        if isequal(img(i,j),0)
                            img(i,j) = 1;
                        else
                            img(i,j) = 0;
                        end
                    end
                end

            end
            
            % Boundary contour
            imcontour(img, 1)

            boundary = bwboundaries(img);
            x = boundary{1}(:,2);
            y = boundary{1}(:,1);

            plot(x, y, '-r', 'LineWidth', 2);
            bwperim
            figure;
            ax = axes;
            imshow(img, [], 'Parent', ax);


            app.Image.ImageSource = img;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageGridLayout = uigridlayout(app.UIFigure);
            app.CloseButton = uibutton(app.ImageGridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tag = 'p3_back';
            app.CloseButton.Text = 'Close';
            app.Image = uiimage(app.ImageGridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageSFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end