classdef mainSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImagePanel              matlab.ui.container.Panel
        GridLayout8             matlab.ui.container.GridLayout
        ImageBackButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        MainGridLayout          matlab.ui.container.GridLayout
        CentralPanel            matlab.ui.container.Panel
        CentralGridLayout       matlab.ui.container.GridLayout
        AlgorithmPanel          matlab.ui.container.Panel
        AlgorithmGridLayout     matlab.ui.container.GridLayout
        AlgorithmDropDown       matlab.ui.control.DropDown
        AlgorithmDropDownLabel  matlab.ui.control.Label
        SegPanel                matlab.ui.container.Panel
        SegGridLayout           matlab.ui.container.GridLayout
        SegHandlerPanel         matlab.ui.container.Panel
        SegHandlerGridLayout    matlab.ui.container.GridLayout
        RemoveButton            matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        UploadPanel             matlab.ui.container.Panel
        UploadGridLayout        matlab.ui.container.GridLayout
        UploadButton            matlab.ui.control.Button
        SegListBox              matlab.ui.control.ListBox
        NextButton              matlab.ui.control.Button
        Docs                    matlab.ui.control.Hyperlink
        Author                  matlab.ui.control.Label
        Title                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        filenames % Segmentations' files
        filepaths % Segmentations' path
        selectedSegmentations % Index of the selected segmentations
        inputApp % inputs' app
    end
    
    methods (Access = private)
        
        function result = isEnabled(~, button)
            enableString = string(button.Enable);
            result = strcmpi(enableString, "on");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputApp)
            % Hide not needed panels
            app.ImagePanel.Visible = "off";

            % Disable segmentations handlers buttons
            app.RemoveButton.Enable = "off";
            app.OpenButton.Enable = "off";
            app.NextButton.Enable = "off";
            
            % Center the gui
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = "on";

            % Initialize variables
            app.filenames = {};
            app.filepaths = {};
            app.selectedSegmentations = [];
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.inputApp = inputSFT(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %Check if a segmentation is selected
            if isempty(app.selectedSegmentations)
                return
            end

            %View the selected segmentation 
    
            %Get the file
            filename = app.filenames{app.selectedSegmentations};
            filepath = app.filepaths{app.selectedSegmentations};

            %Change panel
            app.CentralPanel.Visible = "off";
            app.ImagePanel.Position = app.CentralPanel.Position;
            app.ImagePanel.Visible = "on";

            %Load the image
            fullname = [filepath, filename];
            imageFile = imread(fullname);
            imagesc(app.UIAxes, imageFile);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            position = app.UIFigure.Position;

            % Upload segmentations
            [inputNames, inputPath] = uigetfile( ...
                {'*.png';'*.jpg'}, ...
                'Select the segmentations you want to fuse', ...
                'MultiSelect','on');

            % Reopen app's window
            % Solve a known issue of the function `uigetfile` (see: https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile)
            figure(app.UIFigure);
            app.UIFigure.Position = position;
            
            % Update ListBox
            if isequal(inputNames, 0)
                fprintf('User selected Cancel\n');
            else
                inputPath = cellstr(repmat(inputPath, length(inputNames), 1)).';
                
                % Store inputs
                app.filenames = [app.filenames, inputNames];
                app.filepaths = [app.filepaths, inputPath];
                
                fprintf('User selected: %s\n', string(inputNames));
                app.SegListBox.Items = cellstr(app.filenames);

                if length(app.filenames) >= 2
                    app.NextButton.Enable = "on";
                end
            end
        end

        % Clicked callback: SegListBox
        function SegListBoxClicked(app, event)
            % Get selected segmentation's index
            item = event.InteractionInformation.Item;
            app.selectedSegmentations = item;

            % Enable buttons
            if ~app.isEnabled(app.RemoveButton)
                app.RemoveButton.Enable ="on";
            end
            if ~app.isEnabled(app.OpenButton)
                app.OpenButton.Enable ="on";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Check if there is a segmentation selected
            if ~isempty(app.selectedSegmentations)
                % Remove the segmentation
                app.filenames(:,app.selectedSegmentations) = [];
                app.filepaths(:,app.selectedSegmentations) = [];

                % Update the ListBox
                app.SegListBox.Items = cellstr(app.filenames);
                
                if app.isEnabled(app.NextButton) && length(app.filenames) < 2
                    app.NextButton.Enable = "off";
                end

                % Remove selected segmentations and disable buttons
                app.selectedSegmentations = [];
                app.OpenButton.Enable = "off";
                app.RemoveButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.MainGridLayout);
            app.Title.Text = '2DSFT - "2 dimensional segmentation fusion tool"';
            app.Author = uilabel(app.MainGridLayout);
            app.Author.Tag = 'author';
            app.Author.Text = '@LorenzoDrudi';
            app.Docs = uihyperlink(app.MainGridLayout);
            app.Docs.Tag = 'docsLink';
            app.Docs.Text = 'Docs';
            app.CentralPanel = uipanel(app.MainGridLayout);
            app.CentralGridLayout = uigridlayout(app.CentralPanel);
            app.NextButton = uibutton(app.CentralGridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'p1_next';
            app.NextButton.Text = 'Next';
            app.SegPanel = uipanel(app.CentralGridLayout);
            app.SegGridLayout = uigridlayout(app.SegPanel);
            app.SegListBox = uilistbox(app.SegGridLayout);
            app.SegListBox.Items = {};
            app.SegListBox.Tag = 'p1_segmentationsList';
            app.SegListBox.ClickedFcn = createCallbackFcn(app, @SegListBoxClicked, true);
            app.SegListBox.Value = {};
            app.UploadPanel = uipanel(app.SegGridLayout);
            app.UploadGridLayout = uigridlayout(app.UploadPanel);
            app.UploadButton = uibutton(app.UploadGridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tag = 'p1_uploadButton';
            app.UploadButton.Text = 'Upload';
            app.SegHandlerPanel = uipanel(app.SegGridLayout);
            app.SegHandlerGridLayout = uigridlayout(app.SegHandlerPanel);
            app.OpenButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tag = 'p1_openButton';
            app.OpenButton.Text = 'Open';
            app.RemoveButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tag = 'p1_removeButton';
            app.RemoveButton.Text = 'Remove';
            app.AlgorithmPanel = uipanel(app.CentralGridLayout);
            app.AlgorithmGridLayout = uigridlayout(app.AlgorithmPanel);
            app.AlgorithmDropDownLabel = uilabel(app.AlgorithmGridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.AlgorithmGridLayout);
            app.AlgorithmDropDown.Items = {'STAPLE', 'Local MAP STAPLE'};
            app.AlgorithmDropDown.Tag = 'p1_algorithmsList';
            app.AlgorithmDropDown.Value = 'STAPLE';
            app.ImagePanel = uipanel(app.UIFigure);
            app.GridLayout8 = uigridlayout(app.ImagePanel);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ImageBackButton = uibutton(app.GridLayout8, 'push');
            app.ImageBackButton.Tag = 'p3_back';
            app.ImageBackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainSFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end